classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        b1                      matlab.ui.control.Button
        cuerda_dd               matlab.ui.control.DropDown
        SeleccionarcuerdaLabel  matlab.ui.control.Label
        axes4                   matlab.ui.control.UIAxes
        axes3                   matlab.ui.control.UIAxes
        axes2                   matlab.ui.control.UIAxes
        axes1                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: b1
        function b1ButtonPushed(app, event)
            cla(app.axes1,"reset")
            cla(app.axes3,"reset")
            cla(app.axes4,"reset")
            % Configura los parámetros de la grabación
            fs = 44100; % Frecuencia de muestreo en Hz
            duracion = 3; % Duración de la grabación en segundos
            grabadora = audiorecorder(fs, 16, 1); % Crear un objeto de grabación
            disp('Coloca el micrófono cerca de la guitarra y toca una cuerda...');
            recordblocking(grabadora, duracion); % Iniciar la grabación y esperar a que termine
            disp('Grabación completada.');
            play(grabadora);
            
            % Obtener los datos de audio grabados
            audioGrabado = getaudiodata(grabadora);
            N = length(audioGrabado);
            n= 0:N-1;
            plot(app.axes1,n/fs,audioGrabado);
            xlim(app.axes1,[0 5])
            
            %señal captada en el dominio del tiempo
            L = 20e-3*fs; %tamaño de la ventana corresponde un tiempo de L/Fs segundos.
            overlap=floor(0.5*L); % es una fracción de L en porcentaje.
            nfft=2^nextpow2(L); % Tamaño de la ventana con ceros adicionados (zero padding).
            % Espectrograma
            % [S,F,T]=spectrogram(audioGrabado,nfft,fs,hamming(L),overlap); colorbar
            % imagesc(app.axes2,F,T,log(abs(S)));
            % colorbar
            %transformada
            X=fft(audioGrabado);
            k=n;
            fk=k*fs/N;
            % [mm, ii]=max(abs(X1(1:N/2)));
            % (ii-1)*Fs/N;
            %potencia espectral
            pot=abs(X);
            [a,b]=max(pot);
            fo=fk(b);
            plot(app.axes3,fk(1:1000*N/fs),pot(1:1000*N/fs))
            xlabel(app.axes3,'frecuencia')
            ylabel(app.axes3,'pot espectral')
            c=linspace(0,5);
            freq_afin=[329.6 246.9 196 146.8 146.8 110];
            Instrumento = 0;
            if Instrumento == 0
                d=freq_afin(app.cuerda_dd.Value)*ones(size(c));
                hold(app.axes4,"on")
                xlim(app.axes4,[0 freq_afin(app.cuerda_dd.Value)*2])
                plot(app.axes4,d,c,'r','lineWidth',1)
                e=fo*ones(size(c));
                plot(app.axes4,e,c,'b','lineWidth',2)
                hold(app.axes4,"off")
            else 

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes1 = uiaxes(app.UIFigure);
            title(app.axes1, 'Title')
            xlabel(app.axes1, 'X')
            ylabel(app.axes1, 'Y')
            zlabel(app.axes1, 'Z')
            app.axes2 = uiaxes(app.UIFigure);
            title(app.axes2, 'Title')
            xlabel(app.axes2, 'X')
            ylabel(app.axes2, 'Y')
            zlabel(app.axes2, 'Z')
            app.axes3 = uiaxes(app.UIFigure);
            title(app.axes3, 'Title')
            xlabel(app.axes3, 'X')
            ylabel(app.axes3, 'Y')
            zlabel(app.axes3, 'Z')
            app.axes4 = uiaxes(app.UIFigure);
            title(app.axes4, 'Title')
            xlabel(app.axes4, 'X')
            ylabel(app.axes4, 'Y')
            zlabel(app.axes4, 'Z')
            app.SeleccionarcuerdaLabel = uilabel(app.UIFigure);
            app.SeleccionarcuerdaLabel.Text = 'Seleccionar cuerda:';
            app.cuerda_dd = uidropdown(app.UIFigure);
            app.cuerda_dd.Items = {'1ra cuerda', '2da cuerda', '3ra cuerda', '4ta cuerda', '5ta cuerda', '6ta cuerda'};
            app.cuerda_dd.ItemsData = [1 2 3 4 5 6];
            app.cuerda_dd.Value = 1;
            app.b1 = uibutton(app.UIFigure, 'push');
            app.b1.ButtonPushedFcn = createCallbackFcn(app, @b1ButtonPushed, true);
            app.b1.Text = 'Grabar';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end